classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MCTOFrameworkUIFigure      matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LoadTGFFFileButton         matlab.ui.control.Button
        AlgorithmsPropertiesLabel  matlab.ui.control.Label
        EncodingDropDown           matlab.ui.control.DropDown
        EncodingDropDownLabel      matlab.ui.control.Label
        RUNButton                  matlab.ui.control.Button
        TasksAmountEditField       matlab.ui.control.NumericEditField
        TasksAmountEditFieldLabel  matlab.ui.control.Label
        WeightEditField            matlab.ui.control.EditField
        WeightEditFieldLabel       matlab.ui.control.Label
        TargetEditField            matlab.ui.control.EditField
        TargetEditFieldLabel       matlab.ui.control.Label
        SourceEditField            matlab.ui.control.EditField
        SourceEditFieldLabel       matlab.ui.control.Label
        TasksGraphLabel            matlab.ui.control.Label
        ProcessorsGridLabel        matlab.ui.control.Label
        ColumnsEditField           matlab.ui.control.NumericEditField
        ColumnsEditField_2Label    matlab.ui.control.Label
        RowsEditField              matlab.ui.control.NumericEditField
        RowsEditField_2Label       matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        numRows    = 4;  % Processors Grid: rows
        numColumns = 4;  % Processors Grid: columns
        numTasks   = 5;  % Tasks Graph: number of tasks
        sourceIds  = [1 1 2 3 3 4]; % Tasks Graph: source nodes ids
        targetIds  = [2 3 4 4 5 5]; % Tasks Graph: target nodes ids
        weights    = [10 20 30 40 50 60]; % Tasks Graph: weights
        encoding   = 6; % Encoding scheme of each decision variable
        dec;
        obj;
        con;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            addpath(genpath([pwd,'\PlatEMO_v4_1']))
            
            params = {app.numTasks,app.numRows,app.numColumns,app.sourceIds,app.targetIds,app.weights,app.encoding};

            [app.dec, app.obj, app.con] = platemo('problem',@ManyCoreV1,'algorithm',@NSGAII,'parameter',params,'save', 0);
            
            solution = app.dec(1,:);
            disp(['Solution: ', num2str(solution)]);
            plotSolution(app.numRows,app.numColumns, solution);
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            app.numColumns = app.ColumnsEditField.Value;
            disp(app.numColumns);
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            app.numRows = app.RowsEditField.Value;
            disp(app.numRows);
        end

        % Value changed function: TasksAmountEditField
        function TasksAmountEditFieldValueChanged(app, event)
            app.numTasks = app.TasksAmountEditField.Value;
            disp(app.numTasks);
        end

        % Value changed function: SourceEditField
        function SourceEditFieldValueChanged(app, event)
            value = app.SourceEditField.Value;
            numArray = str2num(value);
            if isnumeric(numArray)
                app.sourceIds = numArray;
            end
        end

        % Value changed function: TargetEditField
        function TargetEditFieldValueChanged(app, event)
            value = app.TargetEditField.Value;
            numArray = str2num(value);
            if isnumeric(numArray)
                app.targetIds = numArray;
            end
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            value = app.WeightEditField.Value;
            numArray = str2num(value);
            if isnumeric(numArray)
                app.weights = numArray;
            end
        end

        % Value changed function: EncodingDropDown
        function EncodingDropDownValueChanged(app, event)
            value = app.EncodingDropDown.Value;
            switch value
                case '1.Real'
                    app.encoding = 1;
                case '2.Integer'
                    app.encoding = 2;
                case '3.Label'
                    app.encoding = 3;
                case '4.Binary'
                    app.encoding = 4;
                case '5.Permutation'
                    app.encoding = 5;
                case '6.User-defined'
                    app.encoding = 6;
            end
        end

        % Button pushed function: LoadTGFFFileButton
        function LoadTGFFFileButtonPushed(app, event)
            % open dialog box to load file
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MCTOFrameworkUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MCTOFrameworkUIFigure = uifigure('Visible', 'off');
            app.MCTOFrameworkUIFigure.Name = 'MCTO Framework';
            app.MCTOFrameworkUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.MCTOFrameworkUIFigure.Tag = 'v1.0';
            app.GridLayout = uigridlayout(app.MCTOFrameworkUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RowsEditField_2Label = uilabel(app.LeftPanel);
            app.RowsEditField_2Label.Text = 'Rows';
            app.RowsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RowsEditField.Limits = [1 Inf];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Value = 4;
            app.ColumnsEditField_2Label = uilabel(app.LeftPanel);
            app.ColumnsEditField_2Label.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ColumnsEditField.Limits = [1 Inf];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Value = 4;
            app.ProcessorsGridLabel = uilabel(app.LeftPanel);
            app.ProcessorsGridLabel.Text = 'Processors Grid';
            app.TasksGraphLabel = uilabel(app.LeftPanel);
            app.TasksGraphLabel.Text = 'Tasks Graph';
            app.SourceEditFieldLabel = uilabel(app.LeftPanel);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.LeftPanel, 'text');
            app.SourceEditField.ValueChangedFcn = createCallbackFcn(app, @SourceEditFieldValueChanged, true);
            app.SourceEditField.Value = '1 1 2 3 3 4';
            app.TargetEditFieldLabel = uilabel(app.LeftPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.LeftPanel, 'text');
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.TargetEditField.Value = '2 3 4 4 5 5';
            app.WeightEditFieldLabel = uilabel(app.LeftPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.LeftPanel, 'text');
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Value = '10 20 30 40 50 60';
            app.TasksAmountEditFieldLabel = uilabel(app.LeftPanel);
            app.TasksAmountEditFieldLabel.Text = 'Tasks Amount';
            app.TasksAmountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TasksAmountEditField.Limits = [1 Inf];
            app.TasksAmountEditField.ValueChangedFcn = createCallbackFcn(app, @TasksAmountEditFieldValueChanged, true);
            app.TasksAmountEditField.Value = 5;
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.EncodingDropDownLabel = uilabel(app.LeftPanel);
            app.EncodingDropDownLabel.Text = 'Encoding';
            app.EncodingDropDown = uidropdown(app.LeftPanel);
            app.EncodingDropDown.Items = {'1.Real', '2.Integer', '3.Label', '4.Binary', '5.Permutation', '6.User-defined'};
            app.EncodingDropDown.ValueChangedFcn = createCallbackFcn(app, @EncodingDropDownValueChanged, true);
            app.EncodingDropDown.Value = '6.User-defined';
            app.AlgorithmsPropertiesLabel = uilabel(app.LeftPanel);
            app.AlgorithmsPropertiesLabel.Text = 'Algorithms Properties';
            app.LoadTGFFFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadTGFFFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTGFFFileButtonPushed, true);
            app.LoadTGFFFileButton.Text = 'Load TGFF File';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.MCTOFrameworkUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MCTOFrameworkUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MCTOFrameworkUIFigure)
        end
    end
end