classdef mctor_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Single                        matlab.ui.container.Tab
        LeftPanel                     matlab.ui.container.Panel
        ViewGraphButton               matlab.ui.control.Button
        TasksAmountEditField_2Label   matlab.ui.control.Label
        RUNButton                     matlab.ui.control.Button
        ProcessorsGridLabel           matlab.ui.control.Label
        RowsEditField                 matlab.ui.control.NumericEditField
        ColumnsEditField              matlab.ui.control.NumericEditField
        TasksGraphLabel               matlab.ui.control.Label
        TasksAmountEditField          matlab.ui.control.EditField
        SourceEditField               matlab.ui.control.EditField
        TargetEditField               matlab.ui.control.EditField
        WeightEditField               matlab.ui.control.EditField
        LoadTGFFFileButton            matlab.ui.control.Button
        AlgorithmsPropertiesLabel     matlab.ui.control.Label
        PopulationSizeEditField       matlab.ui.control.NumericEditField
        NumberOfObjectivesDropDown    matlab.ui.control.DropDown
        NumberofObjectivesDropDownLabel  matlab.ui.control.Label
        PopulationSizeEditFieldLabel  matlab.ui.control.Label
        EncodingDropDown              matlab.ui.control.DropDown
        EncodingDropDownLabel         matlab.ui.control.Label
        VersionLabel                  matlab.ui.control.Label
        WeightEditFieldLabel          matlab.ui.control.Label
        TargetEditFieldLabel          matlab.ui.control.Label
        SourceEditFieldLabel          matlab.ui.control.Label
        ColumnsEditFieldLabel         matlab.ui.control.Label
        RowsEditFieldLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        Graph                         matlab.ui.control.UIAxes
        Batch                         matlab.ui.container.Tab
        LeftPanel_Batch               matlab.ui.container.Panel
        AlgorithmTextArea             matlab.ui.control.TextArea
        AlgorithmTextAreaLabel        matlab.ui.control.Label
        VersionLabel_2                matlab.ui.control.Label
        ProcessorsGridLabel_Batch     matlab.ui.control.Label
        GridSizeTextArea_Batch        matlab.ui.control.TextArea
        BatchParametersLabel          matlab.ui.control.Label
        MutationrateTextAreaLabel     matlab.ui.control.Label
        NumberOfExecutionsEditField   matlab.ui.control.NumericEditField
        PopulationSizeTextArea_Batch  matlab.ui.control.TextArea
        PopulationsizeLabel           matlab.ui.control.Label
        RowsColumnsTextAreaLabel      matlab.ui.control.Label
        PopulationSizeEditField_2Label_2  matlab.ui.control.Label
        MutationRateTextArea          matlab.ui.control.TextArea
        EncodingDropDown_Batch        matlab.ui.control.DropDown
        EncodingDropDown_2Label       matlab.ui.control.Label
        RUNButton_Batch               matlab.ui.control.Button
        STOPButton_Batch              matlab.ui.control.Button
        RightPanel_Batch              matlab.ui.container.Panel
        ExportResultsButton           matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = public)
        
        rootFolder = '';
        matlabVersion = 0;

        %% Single mode panel
        popSize    = 100;   % Population Size
        numObj     = 2;     % Number of objectives (Energy and Fault-tolerance)
        numRows    = 4;  % Processors Grid: rows
        numColumns = 4;  % Processors Grid: columns
        encoding   = 7; % Encoding scheme of each decision variable

        %% Batch mode panel
        numExecutions = 30;
        encodingBatch = 7;
        gridSizeBatch = [4 4; 6 6; 8 8];
        popSizeBatch = [100];
        mutationRateBatch = [0.01];
        algorithmsBatch = ["NSGAII"];
        batchResults;

        %% TGFF/Graphs
        numTasks   = [5];           % Input Graphs: total number of tasks for each application
        sourceIds  = [1 1 2 3 3 4]; % Input Graphs: source nodes ids
        targetIds  = [2 3 4 4 5 5]; % Input Graphs: target nodes ids
        weights    = [10 20 30 40 50 60]; % Input Graphs: arc weights

        %% Common structures
        % Structure to save the solution data
        solution;
        % Figure to represent the chromosome solution
        g;

        %% Control flags
        loadFromFile = false;
        isStopped = false;
    end
            

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rootFolder = pwd;
            addpath(app.rootFolder);
            % addpath(genpath([app.rootFolder,'\resources']));
            addpath(genpath([app.rootFolder,'\tgff_files']));
            addpath(genpath([app.rootFolder,'\thirdparty']));
            addpath(genpath([app.rootFolder,'\PlatEMO']));
            
            v = version('-release');
            app.matlabVersion = str2double(v(1:4));
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % Assembly the parameters
            params = {sum(app.numTasks), app.numRows, app.numColumns, app.popSize, app.numObj, app.sourceIds, app.targetIds, app.weights, app.encoding};
            
            % app.RUNButton.Enable = "off";
            
            % Call PlatEMO
            [dec, obj, ~] = platemo('problem', @ManyCoreMAV1, 'algorithm', @NSGAII, 'parameter', params, 'save', 0);
            cd('..');

            % Create a struct containing the solution
            app.solution = utils.structureSolution(dec, obj);

            dcm = datacursormode(app.UIFigure);
            dcm.Enable = "on";
            dcm.UpdateFcn = {@utils.displaySolutionTip,app};
            
            % Distinct the best solution (based on the euclidian distance
            % to the origin) from the others
            group = repmat("others", [height(app.solution.chromosomes),1]);
            group(app.solution.best.index) = "best";

            % Plot the Pareto-front curve
            % plot(app.Graph, app.solution.energy, app.solution.fault_tolerance, '.', 'MarkerSize', 22);
            gscatter(app.Graph, app.solution.energy, app.solution.fault_tolerance, group,[],'.',[18 22],'on');

            app.RUNButton.Enable = "on";
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            app.numColumns = app.ColumnsEditField.Value;
            utils.log(['[Grid] Columns: ' num2str(app.numColumns)]);
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            app.numRows = app.RowsEditField.Value;
            utils.log(['[Grid] Rows: ' num2str(app.numRows)]);
        end

        % Value changed function: TasksAmountEditField
        function TasksAmountEditFieldValueChanged(app, event)
            value = app.TasksAmountEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Number of Tasks: ' value]);
                app.numTasks = numArray;
            end
        end

        % Value changed function: SourceEditField
        function SourceEditFieldValueChanged(app, event)
            value = app.SourceEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Source Tasks: ' value]);
                app.sourceIds = numArray;
            end
        end

        % Value changed function: TargetEditField
        function TargetEditFieldValueChanged(app, event)
            value = app.TargetEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Target Tasks: ' value]);
                app.targetIds = numArray;
            end
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            value = app.WeightEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Edge Weights: ' value]);
                app.weights = numArray;
            end
        end

        % Value changed function: EncodingDropDown
        function EncodingDropDownValueChanged(app, event)
            value = app.EncodingDropDown.Value;
            app.encoding = utils.getEncodingDropDown(value);
            utils.log(['Encoding: ' num2str(app.encoding)]);
        end

        % Button pushed function: LoadTGFFFileButton
        function LoadTGFFFileButtonPushed(app, event)
            app.ViewGraphButton.Enable = 'off';
            % Open dialog box to load file
            [file, path] = uigetfile({'*.tgffopt'},'Select the TGFF input file',app.rootFolder,'MultiSelect','off');
            if isequal(file, 0)
                app.loadFromFile = false;
                utils.log('User selected cancel');
            else
                
                fullPath = fullfile(path,file);
                utils.log(['User selected ', fullPath]);
                
                % Call TGFF converter]
                input_file = strrep(fullPath,'.tgffopt','');
                output_file = strrep(fullPath,'.tgffopt','.tgff');
                command = [app.rootFolder,'\tgff_files\tgff3_1.exe',' ', input_file];
                [status, ~] = system(command);

                if status == 0
                    metaConfig = tgff.extractMetaInfo(fullPath);
                    % Convert the Graphs from TGFF format to
                    % Source-Target-Weight arrays format
                    [n, s, t, w, nt] = tgff.parse(output_file, metaConfig);
                        
                    app.numTasks  = nt;
                    app.sourceIds = s;
                    app.targetIds = t;
                    app.weights   = w;
                    
                    app.TasksAmountEditField.Value = sprintf('%d ', nt);
                    app.SourceEditField.Value = sprintf('%d ', s);
                    app.TargetEditField.Value = sprintf('%d ', t);
                    app.WeightEditField.Value = sprintf('%0.2f ', w);
                    
                    utils.log(['Number of Tasks: ' num2str(app.TasksAmountEditField.Value)]);
                    utils.log(['Source: ' app.SourceEditField.Value]);
                    utils.log(['Target: ' app.TargetEditField.Value]);
                    utils.log(['Weight: ' app.WeightEditField.Value]);
                    app.loadFromFile = true;
                else
                    msgbox('The TGFF conversor has failed! Please, check the parameters in the .tgffopt file', 'TGFF conversion failed');
                end
            end
            app.ViewGraphButton.Enable = 'on';
        end

        % Value changed function: NumberOfObjectivesDropDown
        function NumberOfObjectivesDropDownValueChanged(app, event)
            value = app.NumberOfObjectivesDropDown.Value;
            switch value
                case '2'
                    app.numObj = 2;
                case '3'
                    app.numObj = 3;
            end
            utils.log(['Number of objectives: ' num2str(app.numObj)]);
        end

        % Value changed function: PopulationSizeEditField
        function PopulationSizeEditFieldValueChanged(app, event)
            app.popSize = app.PopulationSizeEditField.Value;
            utils.log(['Population Size: ' num2str(app.popSize)]);
        end

        % Button pushed function: RUNButton_Batch
        function RUNButton_BatchPushed(app, event)
            % Initialize UITable and get parameters
            paramsGrid = utils.initializeTable(app, app.UITable);
            
            app.RUNButton.Enable = "off";
            app.RUNButton_Batch.Enable = "off";
            
            n = paramsGrid.n;

            % Structure to store the batch execution results
            batch_results = cell(n, 1);

            % Perform the batch execution
            for i = 1:n
                best_objectives = zeros(app.numExecutions, app.numObj);
                best_solutions = cell(app.numExecutions,1);
                % Assembly the parameters
                params = {sum(app.numTasks), paramsGrid.r(i), paramsGrid.c(i), paramsGrid.pop(i), app.numObj, app.sourceIds, app.targetIds, app.weights, app.encodingBatch};
                for j = 1:app.numExecutions
                    if app.isStopped
                        break;
                    end
                    func = str2func(paramsGrid.alg{i,1});
                    [dec, obj, ~] = platemo('problem', @ManyCoreMAV1, 'algorithm', func, 'parameter', params, 'save', 0);
                    b = utils.getBestSolution(dec, obj);
                    best_objectives(j,:) = b.best_result;
                    best_solutions{j,1} = b.best_solution;
                    % Update UITable 'status'
                    utils.updateTableByField(app.UITable,i,'status',strcat(num2str(j),"/",num2str(app.numExecutions)));
                end

                [energy_mean, energy_std, lb_mean, lb_std] = utils.getStatistics(best_objectives);
                energyLabel = strcat(num2str(energy_mean),"/",num2str(energy_std));
                lbLabel = strcat(num2str(lb_mean),"/",num2str(lb_std));

                % Update UITable 'energy average' and 'load balance average'
                utils.updateTableByField(app.UITable,i,'e',energyLabel);
                utils.updateTableByField(app.UITable,i,'lb',lbLabel);
                
                % Store the results
                batch_results{i,1}.best_objectives = best_objectives;
                batch_results{i,1}.best_solutions = best_solutions;
                batch_results{i,1}.mean_energy = energy_mean;
                batch_results{i,1}.std_energy = energy_std;
                batch_results{i,1}.mean_loadbalance = lb_mean;
                batch_results{i,1}.std_loadbalance = lb_std;
                
                if app.isStopped
                    break;
                end
            end
            
            cd('..');
            app.batchResults = batch_results;

            app.isStopped = false;
            app.RUNButton.Enable = "on";
            app.RUNButton_Batch.Enable = "on";
        end

        % Value changed function: GridSizeTextArea_Batch
        function GridSizeTextArea_BatchValueChanged(app, event)
            value = app.GridSizeTextArea_Batch.Value;
            n = height(value);
            a = zeros(n,2);
            for i = 1:n
                % Parse the grid size cells
                dim = split(string(value{i,1}), ",");
                if length(dim) == 2
                    a(i, 1) = str2double(dim(1,1));
                    a(i, 2) = str2double(dim(2,1));
                end
            end
            % Remove zeroed lines
            app.gridSizeBatch = a(any(a,2),:);
            utils.log('[Batch] Grid dimensions: ');
            utils.log(app.gridSizeBatch);
        end

        % Value changed function: NumberOfExecutionsEditField
        function NumberOfExecutionsEditFieldValueChanged(app, event)
            app.numExecutions = app.NumberOfExecutionsEditField.Value;
            utils.log(['[Batch] Number of executions: ' num2str(app.numExecutions)]);
        end

        % Value changed function: PopulationSizeTextArea_Batch
        function PopulationSizeTextArea_BatchValueChanged(app, event)
            value = app.PopulationSizeTextArea_Batch.Value;
            n = height(value);
            a = zeros(n, 1);
            % Convert population size array to double
            a(:) = str2double(value(:));
            % Remove zeroed lines
            app.popSizeBatch = a(any(a,2),:);
            utils.log('[Batch] Population size: ');
            utils.log(app.popSizeBatch);
        end

        % Value changed function: MutationRateTextArea
        function MutationRateTextAreaValueChanged(app, event)
            value = app.MutationRateTextArea.Value;
            n = height(value);
            a = zeros(n, 1);
            % Convert mutation rate array to double
            a(:) = str2double(value(:));
            % Remove zeroed lines
            app.mutationRateBatch = a(any(a,2),:);
            utils.log('[Batch] Mutation rate: ');
            utils.log(app.mutationRateBatch);
        end

        % Value changed function: EncodingDropDown_Batch
        function EncodingDropDown_BatchValueChanged(app, event)
            value = app.EncodingDropDown_Batch.Value;
            app.encodingBatch = utils.getEncodingDropDown(value);
            utils.log(['[Batch] Encoding: ' num2str(app.encodingBatch)]);
        end

        % Button pushed function: STOPButton_Batch
        function STOPButton_BatchPushed(app, event)
            app.isStopped = true;
        end

        % Value changed function: AlgorithmTextArea
        function AlgorithmTextAreaValueChanged(app, event)
            value = app.AlgorithmTextArea.Value;
            n = height(value);
            idx = 1;
            a = {};
            % Remove zeroed lines
            for i=1:n
                v = value{i,1};
                if ~isempty(v)
                    a{idx,1} = v;
                    idx = idx+1;
                end
            end
            app.algorithmsBatch = a;
            utils.log('[Batch] Algorithms: ');
            utils.log(app.algorithmsBatch);
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            sRoot = 'batch/';
            sReportFolder = strcat(sRoot,'results_',string(datetime('now', 'Format', 'yyyyMMdd_HHmmSS')));

            if ~exist(sRoot, 'dir')
                mkdir(sRoot);
            end
            mkdir(sReportFolder)

            batch_results = app.batchResults;
            save(strcat(sReportFolder,'/results.mat'), "batch_results");

            for i=1:height(batch_results)
                writematrix(batch_results{i,1}.best_objectives, strcat(sReportFolder,'/',num2str(i),'_best_objectives.csv'));
                writecell(batch_results{i,1}.best_solutions, strcat(sReportFolder,'/',num2str(i),'_best_solutions.csv'));
            end
        end

        % Button pushed function: ViewGraphButton
        function ViewGraphButtonPushed(app, event)
            s = app.sourceIds;
            t = app.targetIds;

            if ~isempty(s) && ~isempty(t)
                g = digraph(s,t);
                plot(g, 'MarkerSize', 7);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Single = uitab(app.TabGroup);
            app.Single.Title = 'Single';
            app.RightPanel = uipanel(app.Single);
            app.Graph = uiaxes(app.RightPanel);
            xlabel(app.Graph, 'Energy')
            ylabel(app.Graph, 'Load Balance')
            app.LeftPanel = uipanel(app.Single);
            app.RowsEditFieldLabel = uilabel(app.LeftPanel);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.ColumnsEditFieldLabel = uilabel(app.LeftPanel);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.SourceEditFieldLabel = uilabel(app.LeftPanel);
            app.SourceEditFieldLabel.Text = 'Source';
            app.TargetEditFieldLabel = uilabel(app.LeftPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.WeightEditFieldLabel = uilabel(app.LeftPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.VersionLabel = uilabel(app.LeftPanel);
            app.VersionLabel.Text = 'v1.0 2023';
            app.EncodingDropDownLabel = uilabel(app.LeftPanel);
            app.EncodingDropDownLabel.Text = 'Encoding';
            app.EncodingDropDown = uidropdown(app.LeftPanel);
            app.EncodingDropDown.Items = {'1.Real', '2.Integer', '3.Label', '4.Binary', '5.Permutation', '6.User-defined 1', '7.User-defined 2'};
            app.EncodingDropDown.ValueChangedFcn = createCallbackFcn(app, @EncodingDropDownValueChanged, true);
            app.EncodingDropDown.Value = '7.User-defined 2';
            app.PopulationSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.NumberofObjectivesDropDownLabel = uilabel(app.LeftPanel);
            app.NumberofObjectivesDropDownLabel.Text = 'Number of Objectives';
            app.NumberOfObjectivesDropDown = uidropdown(app.LeftPanel);
            app.NumberOfObjectivesDropDown.Items = {'2', '3'};
            app.NumberOfObjectivesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberOfObjectivesDropDownValueChanged, true);
            app.NumberOfObjectivesDropDown.Value = '2';
            app.PopulationSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PopulationSizeEditField.Limits = [1 Inf];
            app.PopulationSizeEditField.ValueDisplayFormat = '%.0f';
            app.PopulationSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PopulationSizeEditFieldValueChanged, true);
            app.PopulationSizeEditField.Value = 100;
            app.AlgorithmsPropertiesLabel = uilabel(app.LeftPanel);
            app.AlgorithmsPropertiesLabel.Text = 'Algorithms Properties';
            app.LoadTGFFFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadTGFFFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTGFFFileButtonPushed, true);
            app.LoadTGFFFileButton.Tooltip = {'Load .tgffopt files'};
            app.LoadTGFFFileButton.Text = 'Load TGFF File';
            app.WeightEditField = uieditfield(app.LeftPanel, 'text');
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Value = '10 20 30 40 50 60';
            app.TargetEditField = uieditfield(app.LeftPanel, 'text');
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.TargetEditField.Value = '2 3 4 4 5 5';
            app.SourceEditField = uieditfield(app.LeftPanel, 'text');
            app.SourceEditField.ValueChangedFcn = createCallbackFcn(app, @SourceEditFieldValueChanged, true);
            app.SourceEditField.Value = '1 1 2 3 3 4';
            app.TasksAmountEditField = uieditfield(app.LeftPanel, 'text');
            app.TasksAmountEditField.ValueChangedFcn = createCallbackFcn(app, @TasksAmountEditFieldValueChanged, true);
            app.TasksAmountEditField.Value = '5';
            app.TasksGraphLabel = uilabel(app.LeftPanel);
            app.TasksGraphLabel.Text = 'Tasks Graph';
            app.ColumnsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ColumnsEditField.Limits = [1 Inf];
            app.ColumnsEditField.ValueDisplayFormat = '%.0f';
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Value = 4;
            app.RowsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RowsEditField.Limits = [1 Inf];
            app.RowsEditField.ValueDisplayFormat = '%.0f';
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Value = 4;
            app.ProcessorsGridLabel = uilabel(app.LeftPanel);
            app.ProcessorsGridLabel.Text = 'Processors Grid';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.TasksAmountEditField_2Label = uilabel(app.LeftPanel);
            app.TasksAmountEditField_2Label.Text = 'Tasks Amount';
            app.ViewGraphButton = uibutton(app.LeftPanel, 'push');
            app.ViewGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ViewGraphButtonPushed, true);
            app.ViewGraphButton.Tooltip = {'Visualize Graphs'};
            app.ViewGraphButton.Text = 'V';
            app.Batch = uitab(app.TabGroup);
            app.Batch.Title = 'Batch';
            app.RightPanel_Batch = uipanel(app.Batch);
            app.UITable = uitable(app.RightPanel_Batch);
            app.UITable.ColumnName = {'Grid'; 'Pop.Size'; 'Mut.Rt.'; 'Enc'; 'Algorithm'; 'Status'; 'Energy(Avg/Std)'; 'LoadBal.(Avg/Std)'};
            app.UITable.RowName = {};
            app.ExportResultsButton = uibutton(app.RightPanel_Batch, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export results';
            app.LeftPanel_Batch = uipanel(app.Batch);
            app.STOPButton_Batch = uibutton(app.LeftPanel_Batch, 'push');
            app.STOPButton_Batch.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_BatchPushed, true);
            app.STOPButton_Batch.Text = 'STOP';
            app.RUNButton_Batch = uibutton(app.LeftPanel_Batch, 'push');
            app.RUNButton_Batch.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_BatchPushed, true);
            app.RUNButton_Batch.Text = 'RUN';
            app.EncodingDropDown_2Label = uilabel(app.LeftPanel_Batch);
            app.EncodingDropDown_2Label.Text = 'Encoding';
            app.EncodingDropDown_Batch = uidropdown(app.LeftPanel_Batch);
            app.EncodingDropDown_Batch.Items = {'1.Real', '2.Integer', '3.Label', '4.Binary', '5.Permutation', '6.User-defined 1', '7.User-defined 2'};
            app.EncodingDropDown_Batch.ValueChangedFcn = createCallbackFcn(app, @EncodingDropDown_BatchValueChanged, true);
            app.EncodingDropDown_Batch.Value = '7.User-defined 2';
            app.MutationRateTextArea = uitextarea(app.LeftPanel_Batch);
            app.MutationRateTextArea.ValueChangedFcn = createCallbackFcn(app, @MutationRateTextAreaValueChanged, true);
            app.MutationRateTextArea.Value = {'0.01'};
            app.PopulationSizeEditField_2Label_2 = uilabel(app.LeftPanel_Batch);
            app.PopulationSizeEditField_2Label_2.Text = 'Number of executions';
            app.RowsColumnsTextAreaLabel = uilabel(app.LeftPanel_Batch);
            app.RowsColumnsTextAreaLabel.Text = 'Rows,Columns';
            app.PopulationsizeLabel = uilabel(app.LeftPanel_Batch);
            app.PopulationsizeLabel.Text = 'Population size';
            app.PopulationSizeTextArea_Batch = uitextarea(app.LeftPanel_Batch);
            app.PopulationSizeTextArea_Batch.ValueChangedFcn = createCallbackFcn(app, @PopulationSizeTextArea_BatchValueChanged, true);
            app.PopulationSizeTextArea_Batch.Value = {'100'};
            app.NumberOfExecutionsEditField = uieditfield(app.LeftPanel_Batch, 'numeric');
            app.NumberOfExecutionsEditField.Limits = [1 Inf];
            app.NumberOfExecutionsEditField.ValueDisplayFormat = '%.0f';
            app.NumberOfExecutionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfExecutionsEditFieldValueChanged, true);
            app.NumberOfExecutionsEditField.Value = 30;
            app.MutationrateTextAreaLabel = uilabel(app.LeftPanel_Batch);
            app.MutationrateTextAreaLabel.Text = 'Mutation rate';
            app.BatchParametersLabel = uilabel(app.LeftPanel_Batch);
            app.BatchParametersLabel.Text = 'Batch Parameters';
            app.GridSizeTextArea_Batch = uitextarea(app.LeftPanel_Batch);
            app.GridSizeTextArea_Batch.ValueChangedFcn = createCallbackFcn(app, @GridSizeTextArea_BatchValueChanged, true);
            app.GridSizeTextArea_Batch.Value = {'4,4'; '6,6'; '8,8'};
            app.ProcessorsGridLabel_Batch = uilabel(app.LeftPanel_Batch);
            app.ProcessorsGridLabel_Batch.Text = 'Processors Grid';
            app.VersionLabel_2 = uilabel(app.LeftPanel_Batch);
            app.VersionLabel_2.Text = 'v1.0 2023';
            app.AlgorithmTextAreaLabel = uilabel(app.LeftPanel_Batch);
            app.AlgorithmTextAreaLabel.Text = 'Algorithm';
            app.AlgorithmTextArea = uitextarea(app.LeftPanel_Batch);
            app.AlgorithmTextArea.ValueChangedFcn = createCallbackFcn(app, @AlgorithmTextAreaValueChanged, true);
            app.AlgorithmTextArea.Value = {'NSGAII'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mctor_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end